classdef Interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ConnexionTab                    matlab.ui.container.Tab
        InitialisationdelaconnexionLabel  matlab.ui.control.Label
        openport                        matlab.ui.control.Button
        closeport                       matlab.ui.control.Button
        ChoixduportCOMEditFieldLabel    matlab.ui.control.Label
        port                            matlab.ui.control.EditField
        tatdelaconnexionEditFieldLabel  matlab.ui.control.Label
        portState                       matlab.ui.control.EditField
        CommandesmanuelsTab             matlab.ui.container.Tab
        CommandesmanuelLabel            matlab.ui.control.Label
        SendButton                      matlab.ui.control.Button
        InstructionDropDownLabel        matlab.ui.control.Label
        instruction                     matlab.ui.control.DropDown
        TypeDropDownLabel               matlab.ui.control.Label
        type                            matlab.ui.control.DropDown
        ValueEditFieldLabel             matlab.ui.control.Label
        value                           matlab.ui.control.NumericEditField
        MoteurDropDownLabel             matlab.ui.control.Label
        moteur                          matlab.ui.control.DropDown
    end


    properties (Access = public)
        table = load('table.mat'); % Load le tableau qui contient toutes les fonctions necessaires de TMCL
    end

    properties (Access = public)
        fid;
    end

    methods (Access = public)
    
        function answer = ReadWrite(app) % WORK IN PROGRESS
            
            n = int32(app.value.Value);
            
            byte(1:4) = uint8([str2double(app.moteur.Value),app.instruction.Value,app.type.Value,0]);  %Le 0 pas certain!!!
            byte(5)= uint8(bitand(bitshift(n,-24),255) );
            byte(6)= uint8(bitand(bitshift(n,-16),255) );
            byte(7)= uint8(bitand(bitshift(n,-8),255) );
            byte(8)= uint8(bitand(n,255) );
            byte(9)=uint8(bitand(sum(byte(1:8)),255) );
            
            byte
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.instruction.Items = app.table.instruction.keys;                    %Setter les titres des instructions :keys
            app.instruction.ItemsData = app.table.instruction.values;              %Setter les itemsData des instructions: values
            
            app.type.Items = app.table.type{1}.keys;                               %Setter les titres des types de la premiere instruction par defaut
            app.type.ItemsData = app.table.type{1}.values;                         %Setter les itemsData des types de l'instruction par defaut
            app.value.Limits = app.table.limitetype{1};                            %Setter les limites de valeurs du premier type par defaut.. A revoir
            
            app.SendButton.Enable = 'off';                                         %Envoie de message est par defaut non-accessible
            
            %Lire les capteurs....A faire ici
            
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            
            %Envoyer un message de 9 bytes au controleur
            %Lire un message de 9 bytes du controleurs
            
            %reponse = WriteRead(app.moteur.Value, app.instruction.Value, app.type.Value, app.value.Value);
            ReadWrite(app)
            fprintf('--------------------------------')
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                fclose(app.fid);
            end
            delete(app);
            
        end

        % Button pushed function: closeport
        function closeportButtonPushed(app, event)
            fclose(app.fid);
            app.portState.FontColor = [0 0 0];
            app.portState.Value = 'Aucune connexion';
            app.openport.Enable = 'on';
            app.SendButton.Enable = 'off';
        end

        % Value changed function: instruction
        function instructionValueChanged(app, event)
            app.type.Items = app.table.type{app.instruction.Value}.keys;            %les bons keys du tableau avec app.instruction.ItemsData
            app.type.ItemsData = app.table.type{app.instruction.Value}.values;     %les bons values du tableau avec app.instruction.ItemsData
            
        end

        % Button pushed function: openport
        function openportButtonPushed(app, event)
            try
                app.fid = serial(strcat('COM',app.port.Value),'BaudRate',9600, 'DataBits', 8, 'Parity', 'none','StopBits', 1, 'FlowControl', 'none'); % Description
                fopen(app.fid);            
                app.portState.FontColor = [0 1 0];
                app.portState.Value = 'Bravo tu m"impressionnes';
                app.openport.Enable = 'off';
                app.SendButton.Enable = 'on';
            catch
                app.portState.FontColor = [1 0 0];
                app.portState.Value = '...Tu vas finir par yarriver';
                app.openport.Enable = 'off';
                pause(3);
                app.portState.FontColor = [0 0 0];
                app.portState.Value = 'Aucune connexion';
                app.openport.Enable = 'on';
                app.SendButton.Enable = 'off';
            end
            
        end

        % Value changed function: type
        function typeValueChanged(app, event)
            %Changer les limites selon le type
            %app.value.Limits = app.table.limitetype{app.instruction.Value}{app.type.Value};               %[x y] x etant min et y etant max A BIEN FAIRE ATTENTION
             
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ConnexionTab = uitab(app.TabGroup);
            app.ConnexionTab.Title = 'Connexion';
            app.InitialisationdelaconnexionLabel = uilabel(app.ConnexionTab);
            app.InitialisationdelaconnexionLabel.Text = 'Initialisation de la connexion';
            app.openport = uibutton(app.ConnexionTab, 'push');
            app.openport.ButtonPushedFcn = createCallbackFcn(app, @openportButtonPushed, true);
            app.openport.Text = 'Confirmer';
            app.closeport = uibutton(app.ConnexionTab, 'push');
            app.closeport.ButtonPushedFcn = createCallbackFcn(app, @closeportButtonPushed, true);
            app.closeport.Text = 'Fermer le port';
            app.ChoixduportCOMEditFieldLabel = uilabel(app.ConnexionTab);
            app.ChoixduportCOMEditFieldLabel.Text = 'Choix du port: COM';
            app.port = uieditfield(app.ConnexionTab, 'text');
            app.tatdelaconnexionEditFieldLabel = uilabel(app.ConnexionTab);
            app.tatdelaconnexionEditFieldLabel.Text = 'État de la connexion';
            app.portState = uieditfield(app.ConnexionTab, 'text');
            app.portState.Editable = 'off';
            app.portState.Value = 'Aucune connexion';
            app.CommandesmanuelsTab = uitab(app.TabGroup);
            app.CommandesmanuelsTab.Title = 'Commandes manuels';
            app.CommandesmanuelLabel = uilabel(app.CommandesmanuelsTab);
            app.CommandesmanuelLabel.Text = 'Commandes manuel';
            app.SendButton = uibutton(app.CommandesmanuelsTab, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.InstructionDropDownLabel = uilabel(app.CommandesmanuelsTab);
            app.InstructionDropDownLabel.Text = 'Instruction:';
            app.instruction = uidropdown(app.CommandesmanuelsTab);
            app.instruction.ValueChangedFcn = createCallbackFcn(app, @instructionValueChanged, true);
            app.TypeDropDownLabel = uilabel(app.CommandesmanuelsTab);
            app.TypeDropDownLabel.Text = 'Type:';
            app.type = uidropdown(app.CommandesmanuelsTab);
            app.type.ValueChangedFcn = createCallbackFcn(app, @typeValueChanged, true);
            app.ValueEditFieldLabel = uilabel(app.CommandesmanuelsTab);
            app.ValueEditFieldLabel.Text = 'Value:';
            app.value = uieditfield(app.CommandesmanuelsTab, 'numeric');
            app.value.ValueDisplayFormat = '%.0f';
            app.MoteurDropDownLabel = uilabel(app.CommandesmanuelsTab);
            app.MoteurDropDownLabel.Text = 'Moteur #';
            app.moteur = uidropdown(app.CommandesmanuelsTab);
            app.moteur.Items = {'1', '2', '3', '4'};
            app.moteur.Value = '1';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Interface

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end